classdef lab1_zad5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArturMatuszczyklab1_zad5UIFigure  matlab.ui.Figure
        ZakoczButton    matlab.ui.control.Button
        ZmiemnieButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZakoczButton
        function ZakoczButtonPushed(app, event)
            close(app.ArturMatuszczyklab1_zad5UIFigure);
        end

        % Button pushed function: ZmiemnieButton
        function ZmiemnieButtonPushed(app, event)
            set(app.ArturMatuszczyklab1_zad5UIFigure, 'Color', [rand(1),rand(1),rand(1)]);
            set(app.ZakoczButton, 'BackgroundColor', [rand(1),rand(1),rand(1)]);
            set(app.ZmiemnieButton, 'BackgroundColor', [rand(1),rand(1),rand(1)]);
            set(app.ZmiemnieButton, 'fontColor', [rand(1),rand(1),rand(1)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArturMatuszczyklab1_zad5UIFigure = uifigure('Visible', 'off');
            app.ArturMatuszczyklab1_zad5UIFigure.Name = 'Artur Matuszczyk - lab1_zad5';
            app.ZmiemnieButton = uibutton(app.ArturMatuszczyklab1_zad5UIFigure, 'push');
            app.ZmiemnieButton.ButtonPushedFcn = createCallbackFcn(app, @ZmiemnieButtonPushed, true);
            app.ZmiemnieButton.Text = 'Zmień mnie ❤❤❤';
            app.ZakoczButton = uibutton(app.ArturMatuszczyklab1_zad5UIFigure, 'push');
            app.ZakoczButton.ButtonPushedFcn = createCallbackFcn(app, @ZakoczButtonPushed, true);
            app.ZakoczButton.Text = 'Zakończ';
            % Show the figure after all components are created
            app.ArturMatuszczyklab1_zad5UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab1_zad5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArturMatuszczyklab1_zad5UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArturMatuszczyklab1_zad5UIFigure)
        end
    end
end