classdef lab10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab10ArturMatuszczykUIFigure  matlab.ui.Figure
        PlikMenu                      matlab.ui.container.Menu
        WczytajFilmMenu               matlab.ui.container.Menu
        ZakoczMenu                    matlab.ui.container.Menu
        DetekcjanaroznikowLabel       matlab.ui.control.Label
        ObrazbinarnieLabel            matlab.ui.control.Label
        ObrazwskaliszarociLabel       matlab.ui.control.Label
        GwnyobrazLabel                matlab.ui.control.Label
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ZakoczMenu
        function ZakoczMenuSelected(app, event)
            close(app.Lab10ArturMatuszczykUIFigure);
        end

        % Menu selected function: WczytajFilmMenu
        function WczytajFilmMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.avi;*.mp4','Video Files (*.avi,*.mp4)'},'Wybierz plik video');
            video = VideoReader(fullfile(pathname, filename));
            while hasFrame(video)
                frame = readFrame(video);      
                frame = imresize(frame, 0.5);
                imshow(frame, 'Parent', app.UIAxes);  
                grayFrame = rgb2gray(frame);
                imshow(grayFrame, 'Parent', app.UIAxes_2);           
                binaryFrame = im2bw(frame);
                imshow(binaryFrame, 'Parent', app.UIAxes_3);
                    
                points = detectMinEigenFeatures(grayFrame);
                markedFrame = insertMarker(frame, points.Location, '+', 'Color', 'white');
                imshow(markedFrame, 'Parent', app.UIAxes_4);              
                
                pause(1/video.FrameRate);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab10ArturMatuszczykUIFigure = uifigure('Visible', 'off');
            app.Lab10ArturMatuszczykUIFigure.Name = 'Lab10 - Artur Matuszczyk';
            app.PlikMenu = uimenu(app.Lab10ArturMatuszczykUIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajFilmMenu = uimenu(app.PlikMenu);
            app.WczytajFilmMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajFilmMenuSelected, true);
            app.WczytajFilmMenu.Text = 'Wczytaj Film';
            app.ZakoczMenu = uimenu(app.PlikMenu);
            app.ZakoczMenu.MenuSelectedFcn = createCallbackFcn(app, @ZakoczMenuSelected, true);
            app.ZakoczMenu.Text = 'Zakończ';
            app.UIAxes = uiaxes(app.Lab10ArturMatuszczykUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.Lab10ArturMatuszczykUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.Lab10ArturMatuszczykUIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Lab10ArturMatuszczykUIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.GwnyobrazLabel = uilabel(app.Lab10ArturMatuszczykUIFigure);
            app.GwnyobrazLabel.Text = 'Główny obraz';
            app.ObrazwskaliszarociLabel = uilabel(app.Lab10ArturMatuszczykUIFigure);
            app.ObrazwskaliszarociLabel.Text = 'Obraz w skali szarości';
            app.ObrazbinarnieLabel = uilabel(app.Lab10ArturMatuszczykUIFigure);
            app.ObrazbinarnieLabel.Text = 'Obraz binarnie';
            app.DetekcjanaroznikowLabel = uilabel(app.Lab10ArturMatuszczykUIFigure);
            app.DetekcjanaroznikowLabel.Text = 'Detekcja naroznikow';
            % Show the figure after all components are created
            app.Lab10ArturMatuszczykUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab10ArturMatuszczykUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab10ArturMatuszczykUIFigure)
        end
    end
end