classdef lab2_zad1234 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab2ArturMatuszczykUIFigure  matlab.ui.Figure
        maxxEditField                matlab.ui.control.NumericEditField
        maxxEditFieldLabel           matlab.ui.control.Label
        stepEditField                matlab.ui.control.NumericEditField
        stepEditFieldLabel           matlab.ui.control.Label
        minnEditField                matlab.ui.control.NumericEditField
        minnEditFieldLabel           matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        MEditField                   matlab.ui.control.NumericEditField
        MEditField_2Label            matlab.ui.control.Label
        DniTygodniaDropDown          matlab.ui.control.DropDown
        DniTygodniaDropDownLabel     matlab.ui.control.Label
        ObliczButton                 matlab.ui.control.Button
        GenerujButton                matlab.ui.control.Button
        WybranydzienNiewybranoLabel  matlab.ui.control.Label
        Label_B                      matlab.ui.control.Label
        Label_G                      matlab.ui.control.Label
        Label_R                      matlab.ui.control.Label
        KolorowankaButton            matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KolorowankaButton
        function KolorowankaButtonPushed(app, event)
            r=rand(1);
            g=rand(1);
            b=rand(1);
            set(app.Lab2ArturMatuszczykUIFigure, 'Color', [r,g,b]);
            set(app.Label_R,'Text',['R = ', num2str(r)]);
            set(app.Label_G,'Text',['G = ', num2str(g)]);
            set(app.Label_B,'Text',['B = ', num2str(b)]);
        end

        % Value changed function: DniTygodniaDropDown
        function DniTygodniaDropDownValueChanged(app, event)
            value = app.DniTygodniaDropDown.Value;
            set(app.WybranydzienNiewybranoLabel,'Text',['Wybrany dzień: ',value]);
        end

        % Button pushed function: GenerujButton
        function GenerujButtonPushed(app, event)
            m=get(app.MEditField,'Value');
            n=get(app.NEditField,'Value');
            mac=rand(m,n);
            surf(app.UIAxes,mac);
            mesh(app.UIAxes2,mac);
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            minn=get(app.minnEditField,'Value');
            maxx=get(app.maxxEditField,'Value');
            step=get(app.stepEditField,'Value');
            t=minn:step:maxx;
            y=sin(t);
            plot(app.UIAxes3,y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab2ArturMatuszczykUIFigure = uifigure('Visible', 'off');
            app.Lab2ArturMatuszczykUIFigure.Name = 'Lab2 - Artur Matuszczyk';
            app.UIAxes = uiaxes(app.Lab2ArturMatuszczykUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.Lab2ArturMatuszczykUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.Lab2ArturMatuszczykUIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.KolorowankaButton = uibutton(app.Lab2ArturMatuszczykUIFigure, 'push');
            app.KolorowankaButton.ButtonPushedFcn = createCallbackFcn(app, @KolorowankaButtonPushed, true);
            app.KolorowankaButton.Text = 'Kolorowanka';
            app.Label_R = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.Label_R.Text = 'Label_R';
            app.Label_G = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.Label_G.Text = 'Label_G';
            app.Label_B = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.Label_B.Text = 'Label_B';
            app.WybranydzienNiewybranoLabel = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.WybranydzienNiewybranoLabel.Text = 'Wybrany dzien: Nie wybrano';
            app.GenerujButton = uibutton(app.Lab2ArturMatuszczykUIFigure, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.GenerujButton.Text = 'Generuj';
            app.ObliczButton = uibutton(app.Lab2ArturMatuszczykUIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.DniTygodniaDropDownLabel = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.DniTygodniaDropDownLabel.Text = 'Dni Tygodnia';
            app.DniTygodniaDropDown = uidropdown(app.Lab2ArturMatuszczykUIFigure);
            app.DniTygodniaDropDown.Items = {'Poniedziałek', 'Wtorek', 'Środa', 'Czwartek', 'Piątek', 'Sobota', 'Niedziela'};
            app.DniTygodniaDropDown.ValueChangedFcn = createCallbackFcn(app, @DniTygodniaDropDownValueChanged, true);
            app.DniTygodniaDropDown.Value = 'Poniedziałek';
            app.MEditField_2Label = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.MEditField_2Label.Text = 'M';
            app.MEditField = uieditfield(app.Lab2ArturMatuszczykUIFigure, 'numeric');
            app.MEditField.Value = 2;
            app.NEditFieldLabel = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Lab2ArturMatuszczykUIFigure, 'numeric');
            app.NEditField.Value = 2;
            app.minnEditFieldLabel = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.minnEditFieldLabel.Text = 'minn';
            app.minnEditField = uieditfield(app.Lab2ArturMatuszczykUIFigure, 'numeric');
            app.minnEditField.Value = -10;
            app.stepEditFieldLabel = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.Lab2ArturMatuszczykUIFigure, 'numeric');
            app.stepEditField.Value = 0.001;
            app.maxxEditFieldLabel = uilabel(app.Lab2ArturMatuszczykUIFigure);
            app.maxxEditFieldLabel.Text = 'maxx';
            app.maxxEditField = uieditfield(app.Lab2ArturMatuszczykUIFigure, 'numeric');
            app.maxxEditField.Value = 10;
            % Show the figure after all components are created
            app.Lab2ArturMatuszczykUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2_zad1234

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab2ArturMatuszczykUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab2ArturMatuszczykUIFigure)
        end
    end
end