classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lab3ArturMatuszczykUIFigure  matlab.ui.Figure
        PlikMenu                     matlab.ui.container.Menu
        WczytajobrazMenu             matlab.ui.container.Menu
        ZapiszobrazMenu              matlab.ui.container.Menu
        ZakoczMenu                   matlab.ui.container.Menu
        EdycjaMenu                   matlab.ui.container.Menu
        RGBtoGrayMenu                matlab.ui.container.Menu
        GraytoRGBMenu                matlab.ui.container.Menu
        ZmniejszButton               matlab.ui.control.Button
        ZwiekszButton                matlab.ui.control.Button
        liczbaskadowychLabel         matlab.ui.control.Label
        wysokoLabel                  matlab.ui.control.Label
        szerokoLabel                 matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ZakoczMenu
        function ZakoczMenuSelected(app, event)
            close(app.lab3ArturMatuszczykUIFigure);
        end

        % Menu selected function: WczytajobrazMenu
        function WczytajobrazMenuSelected(app, event)
            global obraz obraz_kopia;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz=imread([sciezka,plik]);
            obraz_kopia = obraz;
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [1,214,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Menu selected function: RGBtoGrayMenu
        function RGBtoGrayMenuSelected(app, event)
            global obraz;
            obraz = rgb2gray(obraz);    
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GraytoRGBMenu
        function GraytoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz=obraz_kopia;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ZapiszobrazMenu
        function ZapiszobrazMenuSelected(app, event)
            global obraz;
            [plik, sciezka] = uiputfile({'*.jpg';'*.bmp'; '*.png'}, 'Zapisz obraz');
            imwrite(obraz,[sciezka, plik]);
        end

        % Button pushed function: ZwiekszButton
        function ZwiekszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,1.1);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [1,214,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Button pushed function: ZmniejszButton
        function ZmniejszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,0.89);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [1,214,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lab3ArturMatuszczykUIFigure = uifigure('Visible', 'off');
            app.lab3ArturMatuszczykUIFigure.Name = 'lab3 - Artur Matuszczyk';
            app.PlikMenu = uimenu(app.lab3ArturMatuszczykUIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajobrazMenu = uimenu(app.PlikMenu);
            app.WczytajobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajobrazMenuSelected, true);
            app.WczytajobrazMenu.Text = 'Wczytaj obraz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszobrazMenuSelected, true);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.ZakoczMenu = uimenu(app.PlikMenu);
            app.ZakoczMenu.MenuSelectedFcn = createCallbackFcn(app, @ZakoczMenuSelected, true);
            app.ZakoczMenu.Text = 'Zakończ';
            app.EdycjaMenu = uimenu(app.lab3ArturMatuszczykUIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.RGBtoGrayMenu = uimenu(app.EdycjaMenu);
            app.RGBtoGrayMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBtoGrayMenuSelected, true);
            app.RGBtoGrayMenu.Text = 'RGB to Gray';
            app.GraytoRGBMenu = uimenu(app.EdycjaMenu);
            app.GraytoRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @GraytoRGBMenuSelected, true);
            app.GraytoRGBMenu.Text = 'Gray to RGB';
            app.UIAxes = uiaxes(app.lab3ArturMatuszczykUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.lab3ArturMatuszczykUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.szerokoLabel = uilabel(app.lab3ArturMatuszczykUIFigure);
            app.szerokoLabel.Text = 'szerokość';
            app.wysokoLabel = uilabel(app.lab3ArturMatuszczykUIFigure);
            app.wysokoLabel.Text = 'wysokość';
            app.liczbaskadowychLabel = uilabel(app.lab3ArturMatuszczykUIFigure);
            app.liczbaskadowychLabel.Text = 'liczba składowych';
            app.ZwiekszButton = uibutton(app.lab3ArturMatuszczykUIFigure, 'push');
            app.ZwiekszButton.ButtonPushedFcn = createCallbackFcn(app, @ZwiekszButtonPushed, true);
            app.ZwiekszButton.Text = 'Zwieksz';
            app.ZmniejszButton = uibutton(app.lab3ArturMatuszczykUIFigure, 'push');
            app.ZmniejszButton.ButtonPushedFcn = createCallbackFcn(app, @ZmniejszButtonPushed, true);
            app.ZmniejszButton.Text = 'Zmniejsz';
            % Show the figure after all components are created
            app.lab3ArturMatuszczykUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.lab3ArturMatuszczykUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lab3ArturMatuszczykUIFigure)
        end
    end
end