classdef lab6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6ArturMatuszczykUIFigure  matlab.ui.Figure
        PlikMenu                     matlab.ui.container.Menu
        WczytajobrazMenu             matlab.ui.container.Menu
        ZapiszobrazMenu              matlab.ui.container.Menu
        ZakoczMenu                   matlab.ui.container.Menu
        FiltryMenu                   matlab.ui.container.Menu
        avgMenu                      matlab.ui.container.Menu
        discMenu                     matlab.ui.container.Menu
        laplacianMenu                matlab.ui.container.Menu
        logMenu                      matlab.ui.container.Menu
        motionMenu                   matlab.ui.container.Menu
        prewittMenu                  matlab.ui.container.Menu
        sobelMenu                    matlab.ui.container.Menu
        przywrocMenu                 matlab.ui.container.Menu
        Slider                       matlab.ui.control.Slider
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ZakoczMenu
        function ZakoczMenuSelected(app, event)
            close(app.Lab6ArturMatuszczykUIFigure);
        end

        % Menu selected function: WczytajobrazMenu
        function WczytajobrazMenuSelected(app, event)
            global obraz obraz_kopia obraz_kopia1;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz=imread([sciezka,plik]);
            obraz_kopia1 = obraz;
            obraz_kopia = obraz;
            [x,y,z] = size(obraz);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Callback function
        function RGBtoGrayMenuSelected(app, event)
            global obraz;
            obraz = rgb2gray(obraz);    
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Callback function
        function GraytoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz=obraz_kopia;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ZapiszobrazMenu
        function ZapiszobrazMenuSelected(app, event)
            global obraz;
            [plik, sciezka] = uiputfile({'*.jpg';'*.bmp'; '*.png'}, 'Zapisz obraz');
            imwrite(obraz,[sciezka, plik]);
        end

        % Callback function
        function ZwiekszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,1.1);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Callback function
        function ZmniejszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,0.89);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Callback function
        function RGB1MenuSelected(app, event)
            global obraz
            imshow(obraz(:,:,1),'Parent', app.UIAxes3);
            imshow(obraz(:,:,2),'Parent', app.UIAxes4);
            imshow(obraz(:,:,3),'Parent', app.UIAxes5);

        end

        % Callback function
        function RGB2MenuSelected(app, event)
            global obraz;
            obraz_R = obraz;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes3);
            obraz_G = obraz;
            obraz_G(:,:,1)=0;
            obraz_G(:,:,3)=0;
            imshow(obraz_G,'Parent', app.UIAxes4);
            obraz_B = obraz;
            obraz_B(:,:,2)=0;
            obraz_B(:,:,1)=0;
            imshow(obraz_B,'Parent', app.UIAxes5);
        end

        % Callback function
        function PoziomJasnociSliderValueChanging(app, event)
            global obraz_kopia
            changingValue = event.Value;
            obraz_new = obraz_kopia;
            obraz_new = obraz_new*changingValue;
            imshow(obraz_new,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_new);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
        end

        % Callback function
        function CzerwienSliderValueChanging(app, event)
            global obraz_kopia obraz_kopia1
            changingValue = event.Value;
            obraz_kopia1(:,:,1) = obraz_kopia(:,:,1) * changingValue;
            imshow(obraz_kopia1,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_kopia1);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
            obraz_R = obraz_kopia1;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes3);
            
        end

        % Callback function
        function ZielenSliderValueChanging(app, event)
            global obraz_kopia obraz_kopia1
            changingValue = event.Value;
            obraz_kopia1(:,:,2) = obraz_kopia(:,:,2) * changingValue;
            imshow(obraz_kopia1,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_kopia1);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
            obraz_R = obraz_kopia1;
            obraz_R(:,:,1)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes4);
            
        end

        % Callback function
        function NiebieskiSliderValueChanging(app, event)
            global obraz_kopia obraz_kopia1
            changingValue = event.Value;
            obraz_kopia1(:,:,3) = obraz_kopia(:,:,3) * changingValue;
            imshow(obraz_kopia1,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_kopia1);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
            obraz_R = obraz_kopia1;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,1)=0;
            imshow(obraz_R,'Parent', app.UIAxes5);
        end

        % Menu selected function: avgMenu
        function avgMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('average'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: discMenu
        function discMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('disk'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: laplacianMenu
        function laplacianMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('laplacian'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('log'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: motionMenu
        function motionMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('motion'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: prewittMenu
        function prewittMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('prewitt'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: sobelMenu
        function sobelMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('sobel'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz obraz_kopia;
            [x,y,z] = size(obraz);
            for i = 1:x
                for j = 1:y
                    if obraz_kopia(i,j,1)<=changingValue
                        obraz(i,j,:) = 0;
                    else
                        obraz(i,j,:) = 255;
                    end
                end
            end
            imshow(obraz,'Parent', app.UIAxes2);
        end

        % Callback function
        function przywrocMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz = obraz_kopia;
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: przywrocMenu
        function przywrocMenuSelected2(app, event)
             global obraz obraz_kopia;
            obraz = obraz_kopia;
            imshow(obraz,'Parent',app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab6ArturMatuszczykUIFigure = uifigure('Visible', 'off');
            app.Lab6ArturMatuszczykUIFigure.Name = 'Lab6 - Artur Matuszczyk';
            app.PlikMenu = uimenu(app.Lab6ArturMatuszczykUIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajobrazMenu = uimenu(app.PlikMenu);
            app.WczytajobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajobrazMenuSelected, true);
            app.WczytajobrazMenu.Text = 'Wczytaj obraz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszobrazMenuSelected, true);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.ZakoczMenu = uimenu(app.PlikMenu);
            app.ZakoczMenu.MenuSelectedFcn = createCallbackFcn(app, @ZakoczMenuSelected, true);
            app.ZakoczMenu.Text = 'Zakończ';
            app.FiltryMenu = uimenu(app.Lab6ArturMatuszczykUIFigure);
            app.FiltryMenu.Text = 'Filtry';
            app.avgMenu = uimenu(app.FiltryMenu);
            app.avgMenu.MenuSelectedFcn = createCallbackFcn(app, @avgMenuSelected, true);
            app.avgMenu.Text = 'avg';
            app.discMenu = uimenu(app.FiltryMenu);
            app.discMenu.MenuSelectedFcn = createCallbackFcn(app, @discMenuSelected, true);
            app.discMenu.Text = 'disc';
            app.laplacianMenu = uimenu(app.FiltryMenu);
            app.laplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @laplacianMenuSelected, true);
            app.laplacianMenu.Text = 'laplacian';
            app.logMenu = uimenu(app.FiltryMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.motionMenu = uimenu(app.FiltryMenu);
            app.motionMenu.MenuSelectedFcn = createCallbackFcn(app, @motionMenuSelected, true);
            app.motionMenu.Text = 'motion';
            app.prewittMenu = uimenu(app.FiltryMenu);
            app.prewittMenu.MenuSelectedFcn = createCallbackFcn(app, @prewittMenuSelected, true);
            app.prewittMenu.Text = 'prewitt';
            app.sobelMenu = uimenu(app.FiltryMenu);
            app.sobelMenu.MenuSelectedFcn = createCallbackFcn(app, @sobelMenuSelected, true);
            app.sobelMenu.Text = 'sobel';
            app.przywrocMenu = uimenu(app.Lab6ArturMatuszczykUIFigure);
            app.przywrocMenu.MenuSelectedFcn = createCallbackFcn(app, @przywrocMenuSelected2, true);
            app.przywrocMenu.Text = 'przywroc';
            app.UIAxes = uiaxes(app.Lab6ArturMatuszczykUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.Lab6ArturMatuszczykUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.Slider = uislider(app.Lab6ArturMatuszczykUIFigure);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 125;
            % Show the figure after all components are created
            app.Lab6ArturMatuszczykUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6ArturMatuszczykUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6ArturMatuszczykUIFigure)
        end
    end
end