classdef lab9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab9ArturMatuszczykUIFigure     matlab.ui.Figure
        PlikMenu                        matlab.ui.container.Menu
        WczytajobrazMenu                matlab.ui.container.Menu
        ZapiszobrazMenu                 matlab.ui.container.Menu
        ZakoczMenu                      matlab.ui.container.Menu
        FiltryMenu                      matlab.ui.container.Menu
        avgMenu                         matlab.ui.container.Menu
        discMenu                        matlab.ui.container.Menu
        laplacianMenu                   matlab.ui.container.Menu
        logMenu                         matlab.ui.container.Menu
        motionMenu                      matlab.ui.container.Menu
        prewittMenu                     matlab.ui.container.Menu
        sobelMenu                       matlab.ui.container.Menu
        PrzywrocMenu                    matlab.ui.container.Menu
        KrawedzieMenu                   matlab.ui.container.Menu
        PionpodstawaMenu                matlab.ui.container.Menu
        Pionpodstawa2Menu               matlab.ui.container.Menu
        PionwzmocnionaMenu              matlab.ui.container.Menu
        Pionwzmocniona2Menu             matlab.ui.container.Menu
        PoziompodstawaMenu              matlab.ui.container.Menu
        Poziompodstawa2Menu             matlab.ui.container.Menu
        PoziomwzmocnionaMenu            matlab.ui.container.Menu
        Poziomwzmocniona2Menu           matlab.ui.container.Menu
        SkosnaMenu                      matlab.ui.container.Menu
        SkosnawzmocnionaMenu            matlab.ui.container.Menu
        Skosna2Menu                     matlab.ui.container.Menu
        Skosna2wzmocnionaMenu           matlab.ui.container.Menu
        SkosnaMenu_2                    matlab.ui.container.Menu
        SkosnawzmocnionaMenu_2          matlab.ui.container.Menu
        Skosna2Menu_2                   matlab.ui.container.Menu
        Skosna2wzmocnionaMenu_2         matlab.ui.container.Menu
        dowolna_krawedzMenu             matlab.ui.container.Menu
        dowolna_krawedzwzmocnionaMenu   matlab.ui.container.Menu
        dowolna_krawedz2Menu            matlab.ui.container.Menu
        dowolna_krawedz2wzmocnionaMenu  matlab.ui.container.Menu
        DetekcjaMenu                    matlab.ui.container.Menu
        FASTMenu                        matlab.ui.container.Menu
        HarrisMenu                      matlab.ui.container.Menu
        KazeMenu                        matlab.ui.container.Menu
        MSERelipsaMenu                  matlab.ui.container.Menu
        MSERwyrnionyMenu                matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        tabMenu                         matlab.ui.container.Menu
        kluczujMenu                     matlab.ui.container.Menu
        tloMenu                         matlab.ui.container.Menu
        BlueSlider                      matlab.ui.control.Slider
        BlueSliderLabel                 matlab.ui.control.Label
        GreenSlider                     matlab.ui.control.Slider
        GreenSliderLabel                matlab.ui.control.Label
        RedSlider                       matlab.ui.control.Slider
        RedSliderLabel                  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ZakoczMenu
        function ZakoczMenuSelected(app, event)
            close(app.Lab9ArturMatuszczykUIFigure);
        end

        % Menu selected function: WczytajobrazMenu
        function WczytajobrazMenuSelected(app, event)
            global obraz obraz_kopia obraz_kopia1;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz=imread([sciezka,plik]);
            obraz_kopia1 = obraz;
            obraz_kopia = obraz;
            obraz = imresize(obraz,[640,480]);
            [x,y,z] = size(obraz);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Callback function
        function RGBtoGrayMenuSelected(app, event)
            global obraz;
            obraz = rgb2gray(obraz);    
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Callback function
        function GraytoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz=obraz_kopia;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ZapiszobrazMenu
        function ZapiszobrazMenuSelected(app, event)
            global obraz_kopia;
            [plik, sciezka] = uiputfile({'*.jpg';'*.bmp'; '*.png'}, 'Zapisz obraz');
            imwrite(obraz_kopia,[sciezka, plik]);
        end

        % Callback function
        function ZwiekszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,1.1);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Callback function
        function ZmniejszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,0.89);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Callback function
        function RGB1MenuSelected(app, event)
            global obraz
            imshow(obraz(:,:,1),'Parent', app.UIAxes3);
            imshow(obraz(:,:,2),'Parent', app.UIAxes4);
            imshow(obraz(:,:,3),'Parent', app.UIAxes5);

        end

        % Callback function
        function RGB2MenuSelected(app, event)
            global obraz;
            obraz_R = obraz;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes3);
            obraz_G = obraz;
            obraz_G(:,:,1)=0;
            obraz_G(:,:,3)=0;
            imshow(obraz_G,'Parent', app.UIAxes4);
            obraz_B = obraz;
            obraz_B(:,:,2)=0;
            obraz_B(:,:,1)=0;
            imshow(obraz_B,'Parent', app.UIAxes5);
        end

        % Callback function
        function PoziomJasnociSliderValueChanging(app, event)
            global obraz_kopia
            changingValue = event.Value;
            obraz_new = obraz_kopia;
            obraz_new = obraz_new*changingValue;
            imshow(obraz_new,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_new);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
        end

        % Callback function
        function CzerwienSliderValueChanging(app, event)
            global obraz_kopia obraz_kopia1
            changingValue = event.Value;
            obraz_kopia1(:,:,1) = obraz_kopia(:,:,1) * changingValue;
            imshow(obraz_kopia1,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_kopia1);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
            obraz_R = obraz_kopia1;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes3);
            
        end

        % Callback function
        function ZielenSliderValueChanging(app, event)
            global obraz_kopia obraz_kopia1
            changingValue = event.Value;
            obraz_kopia1(:,:,2) = obraz_kopia(:,:,2) * changingValue;
            imshow(obraz_kopia1,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_kopia1);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
            obraz_R = obraz_kopia1;
            obraz_R(:,:,1)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes4);
            
        end

        % Callback function
        function NiebieskiSliderValueChanging(app, event)
            global obraz_kopia obraz_kopia1
            changingValue = event.Value;
            obraz_kopia1(:,:,3) = obraz_kopia(:,:,3) * changingValue;
            imshow(obraz_kopia1,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_kopia1);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
            obraz_R = obraz_kopia1;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,1)=0;
            imshow(obraz_R,'Parent', app.UIAxes5);
        end

        % Menu selected function: avgMenu
        function avgMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('average'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: discMenu
        function discMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('disk'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: laplacianMenu
        function laplacianMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('laplacian'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('log'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: motionMenu
        function motionMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('motion'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: prewittMenu
        function prewittMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('prewitt'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: sobelMenu
        function sobelMenuSelected(app, event)
            global obraz;
            obraz=imfilter(obraz,fspecial('sobel'));
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Callback function: not associated with a component
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz obraz_kopia;
            [x,y,z] = size(obraz);
            for i = 1:x
                for j = 1:y
                    if obraz_kopia(i,j,1)<=changingValue
                        obraz(i,j,:) = 0;
                    else
                        obraz(i,j,:) = 255;
                    end
                end
            end
            imshow(obraz,'Parent', app.UIAxes2);
        end

        % Callback function
        function przywrocMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz = obraz_kopia;
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: PrzywrocMenu
        function PrzywrocMenuSelected2(app, event)
            global obraz obraz_kopia1;
            obraz = obraz_kopia1;
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: PionpodstawaMenu
        function PionpodstawaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ 1,0,-1;
                      1,0,-1;
                      1,0,-1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: PionwzmocnionaMenu
        function PionwzmocnionaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ 1,0,-1;
                      1,0,-1;
                      1,0,-1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: PoziompodstawaMenu
        function PoziompodstawaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ 1,1,1;
                      0,0,0;
                    -1,-1,-1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: PoziomwzmocnionaMenu
        function PoziomwzmocnionaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ 1,1,1;
                      0,0,0;
                    -1,-1,-1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: SkosnaMenu
        function SkosnaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ 1,1,0;
                      1,0,-1;
                      0,-1,-1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: SkosnaMenu_2
        function SkosnaMenu_2Selected(app, event)
            global obraz obraz_kopia;
            maska = [ 0,1,1;
                      -1,0,1;
                      -1,-1,0];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: dowolna_krawedzMenu
        function dowolna_krawedzMenuSelected(app, event)
            global obraz obraz_kopia;
            maska_pion = [ 1,0,-1;
                      1,0,-1;
                      1,0,-1];
            maska_poziom = [ 1,1,1;
                      0,0,0;
                    -1,-1,-1];
            maska_skos1 = [ 1,1,0;
                      1,0,-1;
                      0,-1,-1];
            maska_skos2 = [ 0,1,1;
                            -1,0,1;
                            -1,-1,0];
            temp_obraz1 = imfilter(obraz,maska_pion);
            temp_obraz2 = imfilter(obraz,maska_poziom);
            temp_obraz3 = imfilter(obraz,maska_skos1);
            temp_obraz4 = imfilter(obraz,maska_skos2);
            obraz_kopia = temp_obraz1+temp_obraz2+temp_obraz3+temp_obraz4;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Pionpodstawa2Menu
        function Pionpodstawa2MenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,0,1;
                      -1,0,1;
                      -1,0,1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Pionwzmocniona2Menu
        function Pionwzmocniona2MenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,0,1;
                      -1,0,1;
                      -1,0,1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Poziompodstawa2Menu
        function Poziompodstawa2MenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,-1,-1;
                      0,0,0;
                    1,1,1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Poziomwzmocniona2Menu
        function Poziomwzmocniona2MenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,-1,-1;
                      0,0,0;
                      1,1,1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: SkosnawzmocnionaMenu
        function SkosnawzmocnionaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ 1,1,0;
                      1,0,-1;
                      0,-1,-1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: SkosnawzmocnionaMenu_2
        function SkosnawzmocnionaMenu_2Selected(app, event)
            global obraz obraz_kopia;
            maska = [ 0,1,1;
                      -1,0,1;
                      -1,-1,0];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: dowolna_krawedzwzmocnionaMenu
        function dowolna_krawedzwzmocnionaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska_pion = [ 1,0,-1;
                      1,0,-1;
                      1,0,-1];
            maska_poziom = [ 1,1,1;
                      0,0,0;
                    -1,-1,-1];
            maska_skos1 = [ 1,1,0;
                      1,0,-1;
                      0,-1,-1];
            maska_skos2 = [ 0,1,1;
                            -1,0,1;
                            -1,-1,0];
            temp_obraz1 = imfilter(obraz,maska_pion*2);
            temp_obraz2 = imfilter(obraz,maska_poziom*2);
            temp_obraz3 = imfilter(obraz,maska_skos1*2);
            temp_obraz4 = imfilter(obraz,maska_skos2*2);
            obraz_kopia = temp_obraz1+temp_obraz2+temp_obraz3+temp_obraz4;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Skosna2Menu
        function Skosna2MenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,-1,0;
                      -1,0,1;
                      0,1,1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Skosna2wzmocnionaMenu
        function Skosna2wzmocnionaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,-1,0;
                      -1,0,1;
                      0,1,1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Skosna2Menu_2
        function Skosna2Menu_2Selected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,-1,0;
                      -1,0,1;
                      0,1,1];
            obraz_kopia = imfilter(obraz,maska);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Skosna2wzmocnionaMenu_2
        function Skosna2wzmocnionaMenu_2Selected(app, event)
            global obraz obraz_kopia;
            maska = [ -1,-1,0;
                      -1,0,1;
                      0,1,1];
            obraz_kopia = imfilter(obraz,maska*2);
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: dowolna_krawedz2Menu
        function dowolna_krawedz2MenuSelected(app, event)
            global obraz obraz_kopia;
            maska_pion = [ -1,0,1;
                      -1,0,1;
                      -1,0,1];
            maska_poziom = [ -1,-1,-1;
                      0,0,0;
                    1,1,1];
            maska_skos1 = [ -1,-1,0;
                      -1,0,1;
                      0,1,1];
            maska_skos2 = [ 0,-1,-1;
                            1,0,-1;
                            1,1,0];
            temp_obraz1 = imfilter(obraz,maska_pion);
            temp_obraz2 = imfilter(obraz,maska_poziom);
            temp_obraz3 = imfilter(obraz,maska_skos1);
            temp_obraz4 = imfilter(obraz,maska_skos2);
            obraz_kopia = temp_obraz1+temp_obraz2+temp_obraz3+temp_obraz4;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: dowolna_krawedz2wzmocnionaMenu
        function dowolna_krawedz2wzmocnionaMenuSelected(app, event)
            global obraz obraz_kopia;
            maska_pion = [ -1,0,1;
                      -1,0,1;
                      -1,0,1];
            maska_poziom = [ -1,-1,-1;
                      0,0,0;
                    1,1,1];
            maska_skos1 = [ -1,-1,0;
                      -1,0,1;
                      0,1,1];
            maska_skos2 = [ 0,-1,-1;
                            1,0,-1;
                            1,1,0];
            temp_obraz1 = imfilter(obraz,maska_pion*2);
            temp_obraz2 = imfilter(obraz,maska_poziom*2);
            temp_obraz3 = imfilter(obraz,maska_skos1*2);
            temp_obraz4 = imfilter(obraz,maska_skos2*2);
            obraz_kopia = temp_obraz1+temp_obraz2+temp_obraz3+temp_obraz4;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: FASTMenu
        function FASTMenuSelected(app, event)
            global obraz obraz_kopia1;
            obraz_kopia1 = rgb2gray(obraz);
            corners =  detectFASTFeatures(obraz_kopia1, MinQuality=0.5,MinContrast=0.5);
            imshow(obraz_kopia1,'Parent', app.UIAxes2);
            figure;
            imshow(obraz);
            hold on;
            plot(corners);

        end

        % Menu selected function: HarrisMenu
        function HarrisMenuSelected(app, event)
            global obraz obraz_kopia1;
            obraz_kopia1 = rgb2gray(obraz);
            corners = detectHarrisFeatures(obraz_kopia1, "FilterSize",5,"MinQuality",0.5);
            imshow(obraz_kopia1,'Parent', app.UIAxes2);
            figure;
            imshow(obraz);
            hold on;
            plot(corners);
        end

        % Menu selected function: KazeMenu
        function KazeMenuSelected(app, event)
            global obraz obraz_kopia1;
            obraz_kopia1 = rgb2gray(obraz);
            corners = detectKAZEFeatures(obraz_kopia1,"Diffusion","sharpedge");
            imshow(obraz_kopia1,'Parent', app.UIAxes2);
            figure;
            imshow(obraz);
            hold on;
            plot(selectStrongest(corners,30));
        end

        % Menu selected function: MSERelipsaMenu
        function MSERelipsaMenuSelected(app, event)
            global obraz obraz_kopia1;
            obraz_kopia1 = rgb2gray(obraz);
            corners = detectMSERFeatures(obraz_kopia1);
            imshow(obraz_kopia1,'Parent', app.UIAxes2);
            figure;
            imshow(obraz);
            hold on;
            plot(corners,'showPixelList',false,'showEllipses',true);

        end

        % Menu selected function: MSERwyrnionyMenu
        function MSERwyrnionyMenuSelected(app, event)
            global obraz obraz_kopia1;
            obraz_kopia1 = rgb2gray(obraz);
            corners = detectMSERFeatures(obraz_kopia1);
            imshow(obraz_kopia1,'Parent', app.UIAxes2);
            figure;
            imshow(obraz);
            hold on;
            plot(corners,'showPixelList',true,'showEllipses',false);
        end

        % Menu selected function: kluczujMenu
        function kluczujMenuSelected(app, event)
            global obraz obraz_kopia Tab Mini Maxi valueR valueG valueB ;
            obraz=obraz_kopia;
            for i = 1:size(obraz_kopia, 1)
                for j = 1:size(obraz_kopia, 2)
                    if((all(obraz_kopia(i,j,1)<Maxi(1)+valueR) && all(obraz_kopia(i,j,1)>Mini(1)-valueR)) || (all(obraz_kopia(i,j,2)<Maxi(2)+valueG) && all(obraz_kopia(i,j,2)>Mini(2)-valueG)) || (all(obraz_kopia(i,j,3)<Maxi(3)+valueB) && all(obraz_kopia(i,j,3)>Mini(3)-valueB)))
                        obraz(i,j,:)=0;
                    end
                end
            end
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Value changing function: RedSlider
        function RedSliderValueChanging(app, event)
            global obraz obraz_kopia Tab Mini Maxi valueR;
            changingValue = event.Value;
            valueR = changingValue;
            obraz=obraz_kopia;
            for i = 1:size(obraz_kopia, 1)
                for j = 1:size(obraz_kopia, 2)
                    if((obraz_kopia(i,j,1)<Maxi(1)+valueR)&&(obraz_kopia(i,j,1)>Mini(1)-valueR))
                        obraz(i,j,:)=0;
                    end
                end
            end
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Value changing function: GreenSlider
        function GreenSliderValueChanging(app, event)
            global obraz obraz_kopia Tab valueG Mini Maxi;
            changingValue = event.Value;
            valueG = changingValue;
            obraz=obraz_kopia;
            for i = 1:size(obraz_kopia, 1)
                for j = 1:size(obraz_kopia, 2)
                    if((obraz_kopia(i,j,2)<Maxi(2)+valueG)&&(obraz_kopia(i,j,2)>Mini(2)-valueG))
                        obraz(i,j,:)=0;
                    end
                end
            end
            imshow(obraz,'Parent',app.UIAxes2);
           
            
        end

        % Value changing function: BlueSlider
        function BlueSliderValueChanging(app, event)
            global obraz obraz_kopia Tab valueB Mini Maxi;
            changingValue = event.Value;
            valueB = changingValue;
            obraz=obraz_kopia;
            for i = 1:size(obraz_kopia, 1)
                for j = 1:size(obraz_kopia, 2)
                    if((obraz_kopia(i,j,3)<Maxi(3)+valueB)&&(obraz_kopia(i,j,3)>Mini(3)-valueB))
                        obraz(i,j,:)=0;
                    end
                end
            end
            imshow(obraz,'Parent',app.UIAxes2);
            
            
        end

        % Menu selected function: tabMenu
        function tabMenuSelected(app, event)
            global obraz obraz_kopia Tab Mini Maxi;
            Tab=impixel(obraz);
            Mini=[min(Tab(:,1)),min(Tab(:,2)),min(Tab(:,3))];
            Maxi=[max(Tab(:,1)),max(Tab(:,2)),max(Tab(:,3))];
            obraz=obraz_kopia;
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: tloMenu
        function tloMenuSelected(app, event)
            global tlo1 obraz; 
            [tlo, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            tlo1=imread([sciezka,tlo]);
            obraz = imresize(obraz,[640, 480]);
            tlo1 = imresize(tlo1,[640,480]);
            for i = 1:size(obraz,1)
               for j = 1:size(obraz,2)
                    if(obraz(i,j,:) == 0)
                        obraz(i,j,:) = tlo1(i,j,:);
                    end
               end
            end
            imshow(obraz,'Parent',app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab9ArturMatuszczykUIFigure = uifigure('Visible', 'off');
            app.Lab9ArturMatuszczykUIFigure.Name = 'Lab9 - Artur Matuszczyk';
            app.PlikMenu = uimenu(app.Lab9ArturMatuszczykUIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajobrazMenu = uimenu(app.PlikMenu);
            app.WczytajobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajobrazMenuSelected, true);
            app.WczytajobrazMenu.Text = 'Wczytaj obraz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszobrazMenuSelected, true);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.ZakoczMenu = uimenu(app.PlikMenu);
            app.ZakoczMenu.MenuSelectedFcn = createCallbackFcn(app, @ZakoczMenuSelected, true);
            app.ZakoczMenu.Text = 'Zakończ';
            app.FiltryMenu = uimenu(app.Lab9ArturMatuszczykUIFigure);
            app.FiltryMenu.Text = 'Filtry';
            app.avgMenu = uimenu(app.FiltryMenu);
            app.avgMenu.MenuSelectedFcn = createCallbackFcn(app, @avgMenuSelected, true);
            app.avgMenu.Text = 'avg';
            app.discMenu = uimenu(app.FiltryMenu);
            app.discMenu.MenuSelectedFcn = createCallbackFcn(app, @discMenuSelected, true);
            app.discMenu.Text = 'disc';
            app.laplacianMenu = uimenu(app.FiltryMenu);
            app.laplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @laplacianMenuSelected, true);
            app.laplacianMenu.Text = 'laplacian';
            app.logMenu = uimenu(app.FiltryMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.motionMenu = uimenu(app.FiltryMenu);
            app.motionMenu.MenuSelectedFcn = createCallbackFcn(app, @motionMenuSelected, true);
            app.motionMenu.Text = 'motion';
            app.prewittMenu = uimenu(app.FiltryMenu);
            app.prewittMenu.MenuSelectedFcn = createCallbackFcn(app, @prewittMenuSelected, true);
            app.prewittMenu.Text = 'prewitt';
            app.sobelMenu = uimenu(app.FiltryMenu);
            app.sobelMenu.MenuSelectedFcn = createCallbackFcn(app, @sobelMenuSelected, true);
            app.sobelMenu.Text = 'sobel';
            app.PrzywrocMenu = uimenu(app.Lab9ArturMatuszczykUIFigure);
            app.PrzywrocMenu.MenuSelectedFcn = createCallbackFcn(app, @PrzywrocMenuSelected2, true);
            app.PrzywrocMenu.Text = 'Przywroc';
            app.KrawedzieMenu = uimenu(app.Lab9ArturMatuszczykUIFigure);
            app.KrawedzieMenu.Text = 'Krawedzie';
            app.PionpodstawaMenu = uimenu(app.KrawedzieMenu);
            app.PionpodstawaMenu.MenuSelectedFcn = createCallbackFcn(app, @PionpodstawaMenuSelected, true);
            app.PionpodstawaMenu.Text = 'Pion podstawa';
            app.Pionpodstawa2Menu = uimenu(app.KrawedzieMenu);
            app.Pionpodstawa2Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionpodstawa2MenuSelected, true);
            app.Pionpodstawa2Menu.Text = 'Pion podstawa 2';
            app.PionwzmocnionaMenu = uimenu(app.KrawedzieMenu);
            app.PionwzmocnionaMenu.MenuSelectedFcn = createCallbackFcn(app, @PionwzmocnionaMenuSelected, true);
            app.PionwzmocnionaMenu.Text = 'Pion wzmocniona';
            app.Pionwzmocniona2Menu = uimenu(app.KrawedzieMenu);
            app.Pionwzmocniona2Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionwzmocniona2MenuSelected, true);
            app.Pionwzmocniona2Menu.Text = 'Pion wzmocniona 2';
            app.PoziompodstawaMenu = uimenu(app.KrawedzieMenu);
            app.PoziompodstawaMenu.MenuSelectedFcn = createCallbackFcn(app, @PoziompodstawaMenuSelected, true);
            app.PoziompodstawaMenu.Text = 'Poziom podstawa';
            app.Poziompodstawa2Menu = uimenu(app.KrawedzieMenu);
            app.Poziompodstawa2Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziompodstawa2MenuSelected, true);
            app.Poziompodstawa2Menu.Text = 'Poziom podstawa 2';
            app.PoziomwzmocnionaMenu = uimenu(app.KrawedzieMenu);
            app.PoziomwzmocnionaMenu.MenuSelectedFcn = createCallbackFcn(app, @PoziomwzmocnionaMenuSelected, true);
            app.PoziomwzmocnionaMenu.Text = 'Poziom wzmocniona';
            app.Poziomwzmocniona2Menu = uimenu(app.KrawedzieMenu);
            app.Poziomwzmocniona2Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomwzmocniona2MenuSelected, true);
            app.Poziomwzmocniona2Menu.Text = 'Poziom wzmocniona 2';
            app.SkosnaMenu = uimenu(app.KrawedzieMenu);
            app.SkosnaMenu.MenuSelectedFcn = createCallbackFcn(app, @SkosnaMenuSelected, true);
            app.SkosnaMenu.Text = 'Skosna /';
            app.SkosnawzmocnionaMenu = uimenu(app.KrawedzieMenu);
            app.SkosnawzmocnionaMenu.MenuSelectedFcn = createCallbackFcn(app, @SkosnawzmocnionaMenuSelected, true);
            app.SkosnawzmocnionaMenu.Text = 'Skosna / wzmocniona';
            app.Skosna2Menu = uimenu(app.KrawedzieMenu);
            app.Skosna2Menu.MenuSelectedFcn = createCallbackFcn(app, @Skosna2MenuSelected, true);
            app.Skosna2Menu.Text = 'Skosna / 2';
            app.Skosna2wzmocnionaMenu = uimenu(app.KrawedzieMenu);
            app.Skosna2wzmocnionaMenu.MenuSelectedFcn = createCallbackFcn(app, @Skosna2wzmocnionaMenuSelected, true);
            app.Skosna2wzmocnionaMenu.Text = 'Skosna / 2 wzmocniona';
            app.SkosnaMenu_2 = uimenu(app.KrawedzieMenu);
            app.SkosnaMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SkosnaMenu_2Selected, true);
            app.SkosnaMenu_2.Text = 'Skosna \';
            app.SkosnawzmocnionaMenu_2 = uimenu(app.KrawedzieMenu);
            app.SkosnawzmocnionaMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SkosnawzmocnionaMenu_2Selected, true);
            app.SkosnawzmocnionaMenu_2.Text = 'Skosna \ wzmocniona';
            app.Skosna2Menu_2 = uimenu(app.KrawedzieMenu);
            app.Skosna2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Skosna2Menu_2Selected, true);
            app.Skosna2Menu_2.Text = 'Skosna \ 2';
            app.Skosna2wzmocnionaMenu_2 = uimenu(app.KrawedzieMenu);
            app.Skosna2wzmocnionaMenu_2.MenuSelectedFcn = createCallbackFcn(app, @Skosna2wzmocnionaMenu_2Selected, true);
            app.Skosna2wzmocnionaMenu_2.Text = 'Skosna \ 2 wzmocniona';
            app.dowolna_krawedzMenu = uimenu(app.KrawedzieMenu);
            app.dowolna_krawedzMenu.MenuSelectedFcn = createCallbackFcn(app, @dowolna_krawedzMenuSelected, true);
            app.dowolna_krawedzMenu.Text = 'dowolna_krawedz';
            app.dowolna_krawedzwzmocnionaMenu = uimenu(app.KrawedzieMenu);
            app.dowolna_krawedzwzmocnionaMenu.MenuSelectedFcn = createCallbackFcn(app, @dowolna_krawedzwzmocnionaMenuSelected, true);
            app.dowolna_krawedzwzmocnionaMenu.Text = 'dowolna_krawedz wzmocniona';
            app.dowolna_krawedz2Menu = uimenu(app.KrawedzieMenu);
            app.dowolna_krawedz2Menu.MenuSelectedFcn = createCallbackFcn(app, @dowolna_krawedz2MenuSelected, true);
            app.dowolna_krawedz2Menu.Text = 'dowolna_krawedz 2';
            app.dowolna_krawedz2wzmocnionaMenu = uimenu(app.KrawedzieMenu);
            app.dowolna_krawedz2wzmocnionaMenu.MenuSelectedFcn = createCallbackFcn(app, @dowolna_krawedz2wzmocnionaMenuSelected, true);
            app.dowolna_krawedz2wzmocnionaMenu.Text = 'dowolna_krawedz 2 wzmocniona';
            app.DetekcjaMenu = uimenu(app.Lab9ArturMatuszczykUIFigure);
            app.DetekcjaMenu.Text = 'Detekcja';
            app.FASTMenu = uimenu(app.DetekcjaMenu);
            app.FASTMenu.MenuSelectedFcn = createCallbackFcn(app, @FASTMenuSelected, true);
            app.FASTMenu.Text = 'FAST';
            app.HarrisMenu = uimenu(app.DetekcjaMenu);
            app.HarrisMenu.MenuSelectedFcn = createCallbackFcn(app, @HarrisMenuSelected, true);
            app.HarrisMenu.Text = 'Harris';
            app.KazeMenu = uimenu(app.DetekcjaMenu);
            app.KazeMenu.MenuSelectedFcn = createCallbackFcn(app, @KazeMenuSelected, true);
            app.KazeMenu.Text = 'Kaze';
            app.MSERelipsaMenu = uimenu(app.DetekcjaMenu);
            app.MSERelipsaMenu.MenuSelectedFcn = createCallbackFcn(app, @MSERelipsaMenuSelected, true);
            app.MSERelipsaMenu.Text = 'MSER - elipsa';
            app.MSERwyrnionyMenu = uimenu(app.DetekcjaMenu);
            app.MSERwyrnionyMenu.MenuSelectedFcn = createCallbackFcn(app, @MSERwyrnionyMenuSelected, true);
            app.MSERwyrnionyMenu.Text = 'MSER - wyróżniony';
            app.Menu = uimenu(app.Lab9ArturMatuszczykUIFigure);
            app.Menu.Text = 'Menu';
            app.tabMenu = uimenu(app.Menu);
            app.tabMenu.MenuSelectedFcn = createCallbackFcn(app, @tabMenuSelected, true);
            app.tabMenu.Text = 'tab';
            app.kluczujMenu = uimenu(app.Menu);
            app.kluczujMenu.MenuSelectedFcn = createCallbackFcn(app, @kluczujMenuSelected, true);
            app.kluczujMenu.Text = 'kluczuj';
            app.tloMenu = uimenu(app.Menu);
            app.tloMenu.MenuSelectedFcn = createCallbackFcn(app, @tloMenuSelected, true);
            app.tloMenu.Text = 'tlo';
            app.UIAxes = uiaxes(app.Lab9ArturMatuszczykUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.Lab9ArturMatuszczykUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.RedSliderLabel = uilabel(app.Lab9ArturMatuszczykUIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.Lab9ArturMatuszczykUIFigure);
            app.RedSlider.Limits = [0 50];
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @RedSliderValueChanging, true);
            app.RedSlider.Value = 20;
            app.GreenSliderLabel = uilabel(app.Lab9ArturMatuszczykUIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.Lab9ArturMatuszczykUIFigure);
            app.GreenSlider.Limits = [0 50];
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @GreenSliderValueChanging, true);
            app.GreenSlider.Value = 20;
            app.BlueSliderLabel = uilabel(app.Lab9ArturMatuszczykUIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.Lab9ArturMatuszczykUIFigure);
            app.BlueSlider.Limits = [0 50];
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @BlueSliderValueChanging, true);
            app.BlueSlider.Value = 20;
            % Show the figure after all components are created
            app.Lab9ArturMatuszczykUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab9ArturMatuszczykUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab9ArturMatuszczykUIFigure)
        end
    end
end