classdef lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lab4ArturMatuszczykUIFigure  matlab.ui.Figure
        PlikMenu                     matlab.ui.container.Menu
        WczytajobrazMenu             matlab.ui.container.Menu
        ZapiszobrazMenu              matlab.ui.container.Menu
        ZakoczMenu                   matlab.ui.container.Menu
        EdycjaMenu                   matlab.ui.container.Menu
        RGBtoGrayMenu                matlab.ui.container.Menu
        GraytoRGBMenu                matlab.ui.container.Menu
        RGB1Menu                     matlab.ui.container.Menu
        RGB2Menu                     matlab.ui.container.Menu
        PoziomJasnociSlider          matlab.ui.control.Slider
        PoziomJasnociSliderLabel     matlab.ui.control.Label
        ZmniejszButton               matlab.ui.control.Button
        ZwiekszButton                matlab.ui.control.Button
        liczbaskadowychLabel         matlab.ui.control.Label
        wysokoLabel                  matlab.ui.control.Label
        szerokoLabel                 matlab.ui.control.Label
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ZakoczMenu
        function ZakoczMenuSelected(app, event)
            close(app.lab4ArturMatuszczykUIFigure);
        end

        % Menu selected function: WczytajobrazMenu
        function WczytajobrazMenuSelected(app, event)
            global obraz obraz_kopia;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz=imread([sciezka,plik]);
            obraz_kopia = obraz;
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Menu selected function: RGBtoGrayMenu
        function RGBtoGrayMenuSelected(app, event)
            global obraz;
            obraz = rgb2gray(obraz);    
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GraytoRGBMenu
        function GraytoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz=obraz_kopia;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ZapiszobrazMenu
        function ZapiszobrazMenuSelected(app, event)
            global obraz;
            [plik, sciezka] = uiputfile({'*.jpg';'*.bmp'; '*.png'}, 'Zapisz obraz');
            imwrite(obraz,[sciezka, plik]);
        end

        % Button pushed function: ZwiekszButton
        function ZwiekszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,1.1);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Button pushed function: ZmniejszButton
        function ZmniejszButtonPushed(app, event)
            global obraz;
            obraz=imresize(obraz,0.89);
            [x,y,z] = size(obraz);
            set(app.szerokoLabel,'Text', ['szerokość = ', num2str(x)]);
            set(app.wysokoLabel,'Text', ['wysokość= ', num2str(y)]);
            set(app.liczbaskadowychLabel,'Text', ['liczba składowych= ', num2str(z)]);
            set(app.UIAxes,'Position', [17,409,y,x]);
            imshow(obraz,'Parent', app.UIAxes);
        end

        % Menu selected function: RGB1Menu
        function RGB1MenuSelected(app, event)
            global obraz
            imshow(obraz(:,:,1),'Parent', app.UIAxes3);
            imshow(obraz(:,:,2),'Parent', app.UIAxes4);
            imshow(obraz(:,:,3),'Parent', app.UIAxes5);

        end

        % Menu selected function: RGB2Menu
        function RGB2MenuSelected(app, event)
            global obraz;
            obraz_R = obraz;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent', app.UIAxes3);
            obraz_G = obraz;
            obraz_G(:,:,1)=0;
            obraz_G(:,:,3)=0;
            imshow(obraz_G,'Parent', app.UIAxes4);
            obraz_B = obraz;
            obraz_B(:,:,2)=0;
            obraz_B(:,:,1)=0;
            imshow(obraz_B,'Parent', app.UIAxes5);
        end

        % Value changing function: PoziomJasnociSlider
        function PoziomJasnociSliderValueChanging(app, event)
            global obraz_kopia
            changingValue = event.Value;
            obraz_new = obraz_kopia;
            obraz_new = obraz_new*changingValue;
            imshow(obraz_new,'Parent', app.UIAxes);
            obraz_gray = rgb2gray(obraz_new);
            imshow(obraz_gray, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lab4ArturMatuszczykUIFigure = uifigure('Visible', 'off');
            app.lab4ArturMatuszczykUIFigure.Name = 'lab4 - Artur Matuszczyk';
            app.PlikMenu = uimenu(app.lab4ArturMatuszczykUIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajobrazMenu = uimenu(app.PlikMenu);
            app.WczytajobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajobrazMenuSelected, true);
            app.WczytajobrazMenu.Text = 'Wczytaj obraz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszobrazMenuSelected, true);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.ZakoczMenu = uimenu(app.PlikMenu);
            app.ZakoczMenu.MenuSelectedFcn = createCallbackFcn(app, @ZakoczMenuSelected, true);
            app.ZakoczMenu.Text = 'Zakończ';
            app.EdycjaMenu = uimenu(app.lab4ArturMatuszczykUIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.RGBtoGrayMenu = uimenu(app.EdycjaMenu);
            app.RGBtoGrayMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBtoGrayMenuSelected, true);
            app.RGBtoGrayMenu.Text = 'RGB to Gray';
            app.GraytoRGBMenu = uimenu(app.EdycjaMenu);
            app.GraytoRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @GraytoRGBMenuSelected, true);
            app.GraytoRGBMenu.Text = 'Gray to RGB';
            app.RGB1Menu = uimenu(app.EdycjaMenu);
            app.RGB1Menu.MenuSelectedFcn = createCallbackFcn(app, @RGB1MenuSelected, true);
            app.RGB1Menu.Text = 'RGB 1';
            app.RGB2Menu = uimenu(app.EdycjaMenu);
            app.RGB2Menu.MenuSelectedFcn = createCallbackFcn(app, @RGB2MenuSelected, true);
            app.RGB2Menu.Text = 'RGB 2';
            app.UIAxes = uiaxes(app.lab4ArturMatuszczykUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.lab4ArturMatuszczykUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.lab4ArturMatuszczykUIFigure);
            title(app.UIAxes3, 'R')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.lab4ArturMatuszczykUIFigure);
            title(app.UIAxes4, 'G')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.lab4ArturMatuszczykUIFigure);
            title(app.UIAxes5, 'B')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.szerokoLabel = uilabel(app.lab4ArturMatuszczykUIFigure);
            app.szerokoLabel.Text = 'szerokość';
            app.wysokoLabel = uilabel(app.lab4ArturMatuszczykUIFigure);
            app.wysokoLabel.Text = 'wysokość';
            app.liczbaskadowychLabel = uilabel(app.lab4ArturMatuszczykUIFigure);
            app.liczbaskadowychLabel.Text = 'liczba składowych';
            app.ZwiekszButton = uibutton(app.lab4ArturMatuszczykUIFigure, 'push');
            app.ZwiekszButton.ButtonPushedFcn = createCallbackFcn(app, @ZwiekszButtonPushed, true);
            app.ZwiekszButton.Text = 'Zwieksz';
            app.ZmniejszButton = uibutton(app.lab4ArturMatuszczykUIFigure, 'push');
            app.ZmniejszButton.ButtonPushedFcn = createCallbackFcn(app, @ZmniejszButtonPushed, true);
            app.ZmniejszButton.Text = 'Zmniejsz';
            app.PoziomJasnociSliderLabel = uilabel(app.lab4ArturMatuszczykUIFigure);
            app.PoziomJasnociSliderLabel.Text = 'Poziom Jasności';
            app.PoziomJasnociSlider = uislider(app.lab4ArturMatuszczykUIFigure);
            app.PoziomJasnociSlider.Limits = [0 2];
            app.PoziomJasnociSlider.ValueChangingFcn = createCallbackFcn(app, @PoziomJasnociSliderValueChanging, true);
            app.PoziomJasnociSlider.Value = 1;
            % Show the figure after all components are created
            app.lab4ArturMatuszczykUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.lab4ArturMatuszczykUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lab4ArturMatuszczykUIFigure)
        end
    end
end